classdef appMaquina04MNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        ObterPDFMenu                 matlab.ui.container.Menu
        RelatrioMenu                 matlab.ui.container.Menu
        PginaXptoMenu                matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        DefiniesMenu                 matlab.ui.container.Menu
        AtivarGrelhaMenu             matlab.ui.container.Menu
        MudarPosLegendaMenu          matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ColorPicker                  matlab.ui.control.ColorPicker
        DropDown                     matlab.ui.control.DropDown
        SistemaDinamicoDropDown      matlab.ui.control.DropDown
        SistemaDinmicoDropDownLabel  matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        bgMNumericos                 matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        OutroButton                  matlab.ui.control.ToggleButton
        ODE45Button                  matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        eV0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        eG                           matlab.ui.control.EditField
        ftuvLabel_2                  matlab.ui.control.Label
        eU0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        aEditFieldLabel_2            matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftuvLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
        end

        % Callback function: ExecutarButton, bgMNumericos, nSpinner
        function ExecutarButtonPushed(app, event)
            syms u(t) v(t)
            strF = app.eF.Value;
            f =@(t,u,v) eval(vectorize(strF));
            %f = matlabFunction(str2sym(strF));
            strG = app.eG.Value;
            g =@(t,u,v) eval(vectorize(strG));

            a = app.eA.Value;
            b = app.eB.Value;
            n = app.nSpinner.Value;
            u0 = app.eU0.Value;
            v0 = app.eV0.Value;
            
            MNumericoSelecionado = app.bgMNumericos.SelectedObject.Text;
            
            erroFuncao = MException("MATLAB:Executar:badF",...
                                    "Introduza uma função em t, u e v");
            erroB = MException("MATLAB:Executar:badB",...
                                    "Introduza um b > a");

            try
                try
                    z = g(t,u,v);
                    app.eF.BackgroundColor = "w";
                catch
                    app.eF.BackgroundColor = [1,0,0];
                    throw(erroFuncao)
                end

                if b<=a
                    app.eB.BackgroundColor = "r";
                    throw(erroB)
                else
                    app.eB.BackgroundColor = [1,1,1];
                end

                SED = [diff(u,t)==f(t,u,v), diff(v,t)==g(t,u,v)];
                ci = [u(a)==u0, v(a)==v0];
                [uExata,~]= dsolve(SED,ci);    
                h = matlabFunction(uExata);

                [t,uEuler,~] = NEulerSED(app,f,g,a,b,n,u0,v0);
                %[~,uEulerM,~]   = NEulerMSED(app,f,g,a,b,n,u0,v0);
                [~,uRK2,~]   = NRK2SED(app,f,g,a,b,n,u0,v0);
                [~,uRK4,~] = NRK4SED(app,f,g,a,b,n,u0,v0);
                %[~,uODE,~]   = ODESED(app,f,g,a,b,n,u0,v0);
                [~,uAdamB,~]   = AdamsSED(app,f,g,a,b,n,u0,v0);
                uExata = h(t);
                erroEuler = abs(uExata-uEuler);
                %erroEulerM = abs(uExata-uEulerM);
                erroRK2 = abs(uExata-uRK2);
                erroRK4 = abs(uExata-uRK4);
                %erroODE = abs(uExata-uODE);
                erroAdamB = abs(uExata-uAdamB);
                cor = app.ColorPicker.Value;
                metodo = app.DropDown.Value;
                if metodo == "Exata"
                    plot(app.UIAxes, t, uExata, '-ro', 'Color', cor)
                else
                    plot(app.UIAxes, t, uExata, '-ro', 'Color', 'r')
                end
                hold(app.UIAxes,"on")
                
                switch MNumericoSelecionado
                    case "Euler"
                        if metodo == "Euler"
                            plot(app.UIAxes, t, uEuler, '-b*', 'Color', cor)
                        else
                            plot(app.UIAxes, t, uEuler, '-b*', 'Color', 'b')
                        end
                        title(app.UIAxes, 'Método de Euler')
                        legendaGrafico = ["Exata","Euler"];
                        A = [t.',uExata.',uEuler.',erroEuler.'];
                        nomeColunasTabela = ["t","Exata","Euler","erroEuler"];
                        app.DropDown.Items = {'Exata', 'Euler'};
                    case "EulerM"
                        if metodo == "EulerM"
                            plot(app.UIAxes, t, uEulerM, '-k*', 'Color', cor)
                        else
                            plot(app.UIAxes, t, uEulerM, '-k*', 'Color', 'k')
                        end
                        title(app.UIAxes, 'Método de Euler Melhorado')
                        legendaGrafico = ["Exata","EulerM"];
                        A = [t.',uExata.',uEulerM.',erroEulerM.'];
                        nomeColunasTabela = ["t","Exata","EulerM","erroEulerM"];
                        app.DropDown.Items = {'Exata', 'EulerM'};
                    case "RK2"
                        if metodo == "RK2"
                            plot(app.UIAxes, t, uRK2, '-g+', 'Color', cor)
                        else
                            plot(app.UIAxes, t, uRK2, '-g+', 'Color', 'g')
                        end
                        title(app.UIAxes, 'Método Range Kutta 2')
                        legendaGrafico = ["Exata","RK2"];
                        A = [t.',uExata.',uRK2.',erroRK2.'];
                        nomeColunasTabela = ["t","Exata","RK2","erroRK2"];
                        app.DropDown.Items = {'Exata', 'RK2'};
                    case "RK4"
                        if metodo == "RK4"
                            plot(app.UIAxes, t, uRK4, '-g+', 'Color', cor)
                        else
                            plot(app.UIAxes, t, uRK4, '-g+', 'Color', 'g')
                        end
                        title(app.UIAxes, 'Método Range Kutta 4')
                        legendaGrafico = ["Exata","RK4"];
                        A = [t.',uExata.',uRK4.',erroRK4.'];
                        nomeColunasTabela = ["t","Exata","RK4","erroRK4"];
                        app.DropDown.Items = {'Exata', 'RK4'};
                    case "ODE45"
                        if metodo == "ODE45"
                            plot(app.UIAxes, t, uODE, '-m*', 'Color', cor)
                        else
                            plot(app.UIAxes, t, uODE, '-m*', 'Color', 'm')
                        end
                        title(app.UIAxes, 'Método ODE45')
                        legendaGrafico = ["Exata", "ODE45"];
                         A = [t.',uExata.',uODE.',erroODE.'];
                        nomeColunasTabela = ["t","Exata","ODE45","erroODE45"];
                        app.DropDown.Items = {'Exata', 'ODE45'};

                    case "Outro"
                        if metodo == "Adams"
                            plot(app.UIAxes, t, uAdamB, '-m*', 'Color', cor)
                        else
                            plot(app.UIAxes, t, uAdamB, '-m*', 'Color', 'm')
                        end
                        title(app.UIAxes, 'Método de Adams-Bashforth')
                        legendaGrafico = ["Exata", "AdamsBashforth"];
                        A = [t.', uExata.', uAdamB.', erroAdamB.'];
                        nomeColunasTabela = ["t", "Exata", "AdamsBashforth", "erroAdamsB"];
                        app.DropDown.Items = {'Exata', 'Adams'};
                    case "Todos os Métodos"
                        if metodo == "Euler"
                            plot(app.UIAxes, t, yEuler, '-b*', 'Color', cor)
                        else
                            plot(app.UIAxes, t, yEuler, '-b*', 'Color', 'b')
                        end
                        if metodo == "EulerM"
                            plot(app.UIAxes, t, yEulerM, '-k*', 'Color', cor)
                        else
                            plot(app.UIAxes, t, yEulerM, '-k*', 'Color', 'k')
                        end
                        if metodo == "RK2"
                            plot(app.UIAxes, t, yRK2, '-g+', 'Color', cor)
                        else
                            plot(app.UIAxes, t, yRK2, '-g+', 'Color', 'g')
                        end
                        if metodo == "RK4"
                            plot(app.UIAxes, t, yRK4, '-c*', 'Color', cor)
                        else
                            plot(app.UIAxes, t, yRK4, '-c*', 'Color', 'c')
                        end
                        if metodo == "ODE45"
                            plot(app.UIAxes, t, yODE, '-r+', 'Color', cor)
                        else
                            plot(app.UIAxes, t, yODE, '-r+', 'Color', 'r')
                        end
                        if metodo == "Adams"
                            plot(app.UIAxes, t, yAdamB, '-m*', 'Color', cor)
                        else
                            plot(app.UIAxes, t, yAdamB, '-m*', 'Color', 'm')
                        end
                        title(app.UIAxes, 'Todos os Métodos')
                        legendaGrafico = ["Exata","Euler","RK2", "EulerM", "RK4", "ODE45", "AdamsBashforth"];
                        A = [t.',yExata.',yEuler.',yRK2.',yEulerM.', yRK4.', yODE.', yAdamB.' ,erroEuler.',erroRK2.', erroEulerM.', erroRK4.', erroODE.', erroAdamB.' ];
                        nomeColunasTabela = ["t","Exata","Euler","RK2", "EulerM", "RK4" , "ODE45", "AdamsBashforth" ,"erroEuler","erroRK2", "erroEulerM", "erroRK4", "ODE45", "erroAdamsB" ];
                        app.DropDown.Items = {'Exata', 'Euler', 'EulerM', 'RK2', 'RK4', 'ODE45', 'Adams'};
                end


                hold(app.UIAxes,"off")
                lgd = legend(app.UIAxes,legendaGrafico);
                lgd.Location = 'northwest';
               
                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;  
                app.UITable.ColumnName = nomeColunasTabela;

            catch Me
                errordlg(Me.message,"Erro nos dados do PVI","modal")
            end           

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % app.eF.Value = "y+exp(3*t)";
            % app.eA.Value = 1;
            % app.eB.Value = 1.5;
            % app.nSpinner.Value = 2;
            % app.eU0.Value = 2;
            ExecutarButtonPushed(app, event)
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            SairMenuSelected(app, event)
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open("RelatorioMaquina04MNSED.pdf")
            winopen("RelatorioMaquina04MNSED.docx")
        end

        % Menu selected function: PginaXptoMenu
        function PginaXptoMenuSelected(app, event)
           web("www.isec.pt")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            appAutores
        end

        % Value changed function: SistemaDinamicoDropDown
        function SistemaDinamicoDropDownValueChanged(app, event)
            value = app.SistemaDinamicoDropDown.Value;
            switch value
                case 1
                    strG = "-16*u";
                    a = 0; b = 8; n = 100; u0 = 9; v0 = 0;
                case 2
                case 3
                case 4
                case 5
                case 6
            end
            app.eG.Value = strG;
            app.eA.Value = a; app.eB.Value = b; app.nSpinner.Value = n;
            app.eU0.Value= u0; app.eV0.Value = v0;
            ExecutarButtonPushed(app, event)                 
        end

        % Menu selected function: ObterPDFMenu
        function ObterPDFMenuSelected(app, event)
                    [ficheiro, caminho] = uiputfile('*.pdf', 'Guardar gráfico como PDF');
        if isequal(ficheiro, 0) || isequal(caminho, 0)
            return;
        end
    
        nomeCompleto = fullfile(caminho, ficheiro);
    
        fig = figure('Visible', 'off', 'Position', [100, 100, 800, 1000]);
    
        subplot(2,1,1);
        ax = gca;
        linhas = findall(app.UIAxes, 'Type', 'line');
        copyobj(linhas, ax);
        title(ax, app.UIAxes.Title.String);
        xlabel(ax, app.UIAxes.XLabel.String);
        ylabel(ax, app.UIAxes.YLabel.String);
        lgd = legend(ax, app.UIAxes.Legend.String);
        lgd.Location = app.UIAxes.Legend.Location;
    
        subplot(2,1,2);
        tabela = app.UITable.Data;
        colunas = app.UITable.ColumnName;
        t = uitable(fig, 'Data', tabela{:,:}, 'ColumnName', colunas, ...
                    'Units', 'normalized', 'Position', [0, 0, 1, 0.4]);
    
        print(fig, nomeCompleto, '-dpdf', '-bestfit');
        close(fig);
        end

        % Menu selected function: AtivarGrelhaMenu
        function AtivarGrelhaMenuSelected(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            ExecutarButtonPushed(app, event)
        end

        % Callback function: ColorPicker
        function ColorPickerValueChanged(app, event)
ExecutarButtonPushed(app, event)
        end

        % Menu selected function: MudarPosLegendaMenu
        function MudarPosLegendaMenuSelected(app, event)
                positions = {'northwest', 'southwest', 'southeast', 'northeast'};
                currentPos = app.UIAxes.Legend.Location;
                idx = find(strcmp(currentPos, positions));
                nextIdx = mod(idx, length(positions)) + 1;
                app.UIAxes.Legend.Location = positions{nextIdx}
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {474, 474};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ObterPDFMenu = uimenu(app.FileMenu);
            app.ObterPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ObterPDFMenuSelected, true);
            app.ObterPDFMenu.Text = 'Obter PDF';
            app.RelatrioMenu = uimenu(app.FileMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.PginaXptoMenu = uimenu(app.FileMenu);
            app.PginaXptoMenu.MenuSelectedFcn = createCallbackFcn(app, @PginaXptoMenuSelected, true);
            app.PginaXptoMenu.Text = 'PáginaXpto';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.DefiniesMenu = uimenu(app.UIFigure);
            app.DefiniesMenu.Text = 'Definições';
            app.AtivarGrelhaMenu = uimenu(app.DefiniesMenu);
            app.AtivarGrelhaMenu.MenuSelectedFcn = createCallbackFcn(app, @AtivarGrelhaMenuSelected, true);
            app.AtivarGrelhaMenu.Text = 'Ativar Grelha';
            app.MudarPosLegendaMenu = uimenu(app.DefiniesMenu);
            app.MudarPosLegendaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPosLegendaMenuSelected, true);
            app.MudarPosLegendaMenu.Text = 'Mudar Pos. Legenda';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v)  =  ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 8;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'u0 =';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.ftuvLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel_2.Text = 'g(t,u,v) =  ';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.nSpinner.Value = 100;
            app.bgMNumericos = uibuttongroup(app.LeftPanel);
            app.bgMNumericos.SelectionChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.bgMNumericos.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.bgMNumericos);
            app.EulerButton.Text = 'Euler';
            app.EulerMButton = uitogglebutton(app.bgMNumericos);
            app.EulerMButton.Text = 'EulerM';
            app.RK2Button = uitogglebutton(app.bgMNumericos);
            app.RK2Button.Text = 'RK2';
            app.RK2Button.Value = true;
            app.RK4Button = uitogglebutton(app.bgMNumericos);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uitogglebutton(app.bgMNumericos);
            app.ODE45Button.Text = 'ODE45';
            app.OutroButton = uitogglebutton(app.bgMNumericos);
            app.OutroButton.Text = 'Outro';
            app.TodososMetodosButton = uitogglebutton(app.bgMNumericos);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SistemaDinmicoDropDownLabel = uilabel(app.LeftPanel);
            app.SistemaDinmicoDropDownLabel.Text = 'Sistema Dinâmico:';
            app.SistemaDinamicoDropDown = uidropdown(app.LeftPanel);
            app.SistemaDinamicoDropDown.Items = {'Mola-Massa s/ Amortecimento', 'Mola-Massa  c/ Amortecimento', 'Circuito Elétrico', 'Pêndulo', 'Outro01', 'Outro02'};
            app.SistemaDinamicoDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.SistemaDinamicoDropDown.ValueChangedFcn = createCallbackFcn(app, @SistemaDinamicoDropDownValueChanged, true);
            app.SistemaDinamicoDropDown.Value = '1';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ColorPicker = uicolorpicker(app.LeftPanel);
            app.ColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorPickerValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquina04MNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end