classdef appMaquina05CDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FicheiroMenu                 matlab.ui.container.Menu
        ObterPDFMenu                 matlab.ui.container.Menu
        RelatrioMenu                 matlab.ui.container.Menu
        PginaXPTOMenu                matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        DefiniesMenu                 matlab.ui.container.Menu
        AtivarGrelhaMenu             matlab.ui.container.Menu
        MudarPosLegendaMenu          matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        zfxyTab                      matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        RegrasLabel                  matlab.ui.control.Label
        Regras                       matlab.ui.control.DropDown
        Panel_3                      matlab.ui.container.Panel
        LabelHarmonica               matlab.ui.control.Label
        LabelEqLaplace               matlab.ui.control.Label
        LabelDParcial                matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        yLabel                       matlab.ui.control.Label
        eC                           matlab.ui.control.NumericEditField
        cLabel                       matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        aEditFieldLabel_5            matlab.ui.control.Label
        eH2                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        eD                           matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        eH1                          matlab.ui.control.NumericEditField
        aEditFieldLabel_2            matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eFxy                         matlab.ui.control.EditField
        fxyEditFieldLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        yfxTab                       matlab.ui.container.Tab
        Panel_2                      matlab.ui.container.Panel
        DiferenasFinitasLabel        matlab.ui.control.Label
        CordasLinhasdosGrficosPanel  matlab.ui.container.Panel
        ColorPicker                  matlab.ui.control.ColorPicker
        DropDown                     matlab.ui.control.DropDown
        FormulaSelecionada           matlab.ui.container.ButtonGroup
        CentradasButton              matlab.ui.control.ToggleButton
        RegressivasButton            matlab.ui.control.ToggleButton
        ProgressivasButton           matlab.ui.control.ToggleButton
        ResetButton_2                matlab.ui.control.Button
        ExecutarButton_2             matlab.ui.control.Button
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        xLabel_2                     matlab.ui.control.Label
        eB_2                         matlab.ui.control.NumericEditField
        aEditFieldLabel_8            matlab.ui.control.Label
        eH1_2                        matlab.ui.control.NumericEditField
        aEditFieldLabel_7            matlab.ui.control.Label
        eA_2                         matlab.ui.control.NumericEditField
        aEditFieldLabel_6            matlab.ui.control.Label
        eFx_2                        matlab.ui.control.EditField
        fxLabel                      matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        Pol2CartTab                  matlab.ui.container.Tab
        AnteriorButton               matlab.ui.control.Button
        PrximoButton                 matlab.ui.control.Button
        ConversodeCoordenadasPanel   matlab.ui.container.Panel
        CoordenadasPolaresConvertidasparaCoordenadasCartesianasLabel  matlab.ui.control.Label
        CoordenadasPanel             matlab.ui.container.Panel
        RHO                          matlab.ui.control.EditField
        RHOEditFieldLabel            matlab.ui.control.Label
        THETA                        matlab.ui.control.NumericEditField
        THETAEditFieldLabel          matlab.ui.control.Label
        ResetButton_3                matlab.ui.control.Button
        ConverterButton              matlab.ui.control.Button
        ConversoSwitch               matlab.ui.control.Switch
        ConversoSwitchLabel          matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
%         function removeFileTab(app)
% 
% app.Panel.Parent = app.UIFigure;
% app.UIAxes.Parent = app.UIFigure;
% 
% app.Panel_2.Parent = app.UIFigure;
% app.UIAxes_2.Parent = app.UIFigure;
% app.CordasLinhasdosGrficosPanel.Parent = app.UIFigure;
% app.TabGroup.Visible = 'off';
% 
% set([app.Panel, app.UIAxes], 'Visible', 'off');
% set([app.Panel_2, app.UIAxes_2], 'Visible', 'off');
% set([app.CordasLinhasdosGrficosPanel], 'Visible', 'off');
% 
% switch app.ConversoSwitch.Value
%     case 'zfxyTab'
%         cla(app.UIAxes_2, 'reset')
%         set([app.Panel_2, app.UIAxes_2], 'Visible', 'off');
%         set([app.CordasLinhasdosGrficosPanel], 'Visible', 'off');
%         set([app.Panel, app.UIAxes], 'Visible', 'on');
%         ExecutarButtonPushed(app, []);
%     case 'yfxTab'
%         cla(app.UIAxes, 'reset')
%         set([app.Panel, app.UIAxes], 'Visible', 'off');
%         set([app.CordasLinhasdosGrficosPanel], 'Visible', 'on');
%         set([app.Panel_2, app.UIAxes_2], 'Visible', 'on');
%         ExecutarButton_2Pushed(app, []);
% end
%         end
%     end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, []);
            ExecutarButton_2Pushed(app, []);
            ConverterButtonPushed(app, []);
        end

        % Callback function
        function ExecutarButtonPushed(app, event)

            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;
            
            erroFxy = MException("MATLAB:executar:badFxy",...
                                 "Introduza uma função real em x e y");

            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end

                dfdxsym = diff(f,x);
                LatexDParcial = ['$',...
                   '\displaystyle \frac{\partial z}{\partial x}=',...
                   latex(dfdxsym),...
                   '$'];
                app.LabelDParcial.Text = LatexDParcial;

                LatexEqLaplace = ['$',...
                   '\displaystyle \frac{\partial^2 f}{\partial x^2}+\frac{\partial^2 f}{\partial y^2}=0',...
                   '$'];
                app.LabelEqLaplace.Text = LatexEqLaplace;

                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    corHarmonica = "g";
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";
                end
                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontColor = corHarmonica;
                    
                [x,y] = meshgrid(a:h1:b,c:h2:d);
                z = f(x,y);
                switch app.Regras.Value
                    case "Trapézio"
                        disp('ok1')
                    case "Simpson"
                        disp('ok2')
                    case "Todos"
                        disp('ok3')
                end
                %mesh(app.UIAxes,x,y,z)
                surf(app.UIAxes,x,y,z)
                title(app.UIAxes, 'Teste')
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)
            syms y(x)
            strFx = app.eFx_2.Value;
            f =@(x) eval(vectorize(strFx));
            a = app.eA_2.Value; h = app.eH1_2.Value; b = app.eB_2.Value;
            metodo = app.FormulaSelecionada.SelectedObject.Text;
            escolha = app.DropDown.Value;
            cor = app.ColorPicker.Value;
            try
                dydxsym = diff(f,x);
                dfdx =@(x) eval(vectorize(char(dydxsym)));
                switch metodo
                    case "Progressivas"
                        [x,y,dydxDFP] = NDerivacaoDFP(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroDFP = abs(dydxExata - dydxDFP);
                        A = [x.', y.', dydxExata.', dydxDFP.', erroDFP.'];
                
                        if escolha == "y=f(x)"
                            plot(app.UIAxes_2, x, y, "-r*", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, y, "-r*", 'Color', 'r')
                        end
                        hold(app.UIAxes_2, "on")
                
                        if escolha == "dydxExata"
                            plot(app.UIAxes_2, x, dydxExata, "-g+", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, dydxExata, "-g+", 'Color', 'g')
                        end
                
                        if escolha == "dydxDFP"
                            plot(app.UIAxes_2, x, dydxDFP, "-bo", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, dydxDFP, "-bo", 'Color', 'b')
                        end
                
                        hold(app.UIAxes_2, "off")
                        legendaGrafico = ["y=f(x)", "dydxExata", "dydxDFP"];
                        titulo = "Progressiva";
                        app.DropDown.Items = {'y=f(x)', 'dydxExata', 'dydxDFP'};

                
                    case "Regressivas"
                        [x,y,dydxDFR] = NDerivacaoDFR(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroDFR = abs(dydxExata - dydxDFR);
                        A = [x.', y.', dydxExata.', dydxDFR.', erroDFR.'];
                
                        if escolha == "y=f(x)"
                            plot(app.UIAxes_2, x, y, "-r*", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, y, "-r*", 'Color', 'r')
                        end
                        hold(app.UIAxes_2, "on")
                
                        if escolha == "dydxExata"
                            plot(app.UIAxes_2, x, dydxExata, "-g+", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, dydxExata, "-g+", 'Color', 'g')
                        end
                
                        if escolha == "dydxDFR"
                            plot(app.UIAxes_2, x, dydxDFR, "-bo", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, dydxDFR, "-bo", 'Color', 'b')
                        end
                
                        hold(app.UIAxes_2, "off")
                        legendaGrafico = ["y=f(x)", "dydxExata", "dydxDFR"];
                        titulo = "Regressiva";
                        app.DropDown.Items = {'y=f(x)', 'dydxExata', 'dydxDFR'};
                
                    case "Centradas"
                        [x,y,dydxDFCENT] = NDerivacaoDFCENT(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroDFCENT = abs(dydxExata - dydxDFCENT);
                        A = [x.', y.', dydxExata.', dydxDFCENT.', erroDFCENT.'];
                
                        if escolha == "y=f(x)"
                            plot(app.UIAxes_2, x, y, "-r*", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, y, "-r*", 'Color', 'r')
                        end
                        hold(app.UIAxes_2, "on")
                
                        if escolha == "dydxExata"
                            plot(app.UIAxes_2, x, dydxExata, "-g+", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, dydxExata, "-g+", 'Color', 'g')
                        end
                
                        if escolha == "dydxDFCENT"
                            plot(app.UIAxes_2, x, dydxDFCENT, "-bo", 'Color', cor)
                        else
                            plot(app.UIAxes_2, x, dydxDFCENT, "-bo", 'Color', 'b')
                        end
                
                        hold(app.UIAxes_2, "off")
                        legendaGrafico = ["y=f(x)", "dydxExata", "dydxDFCENT"];
                        titulo = "Centrada";
                        app.DropDown.Items = {'y=f(x)', 'dydxExata', 'dydxDFCENT'};
                    end

                legend(app.UIAxes_2, legendaGrafico)
                title(app.UIAxes_2, titulo);
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Callback function
        function SwitchValueChanged(app, event)

        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           SairMenuSelected(app, event)
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open("RelatorioMaquina05CDI.pdf")
            winopen("RelatorioMaquina05CDI.docx")
        end

        % Menu selected function: PginaXPTOMenu
        function PginaXPTOMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            appAutores
        end

        % Menu selected function: ObterPDFMenu
        function ObterPDFMenuSelected(app, event)
            % Abre uma janela para o utilizador escolher onde guardar o ficheiro PDF
            [ficheiro, caminho] = uiputfile('*.pdf', 'Guardar gráfico como PDF');
            
            % Se o utilizador cancelar, termina a função
            if isequal(ficheiro, 0) || isequal(caminho, 0)
                return;
            end
            
            % Constrói o caminho completo do ficheiro
            nomeCompleto = fullfile(caminho, ficheiro);
            
            % Cria uma figura invisível para gerar o PDF (800x500 px, altura reduzida)
            fig = figure('Visible', 'off', 'Position', [100, 100, 800, 500]);
            
            % Copiar o gráfico existente
            ax = axes('Parent', fig);
            linhas = findall(app.UIAxes, 'Type', 'line');
            copyobj(linhas, ax);
            
            % Copia título e rótulos dos eixos
            title(ax, app.UIAxes.Title.String);
            xlabel(ax, app.UIAxes.XLabel.String);
            ylabel(ax, app.UIAxes.YLabel.String);
            
            % Copia a legenda, mantendo a localização
            if ~isempty(app.UIAxes.Legend)
                lgd = legend(ax, app.UIAxes.Legend.String);
                lgd.Location = app.UIAxes.Legend.Location;
            end
            
            % Exporta a figura como PDF com ajuste automático
            print(fig, nomeCompleto, '-dpdf', '-bestfit');
            
            % Fecha a figura temporária
            close(fig);

        end

        % Menu selected function: AtivarGrelhaMenu
        function AtivarGrelhaMenuSelected(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Menu selected function: MudarPosLegendaMenu
        function MudarPosLegendaMenuSelected(app, event)
            switch app.ConversoSwitch.Value
                case 'zfxyTab'
                    positions = {'northwest', 'southwest', 'southeast', 'northeast'};
                    currentPos = app.UIAxes.Legend.Location;
                    idx = find(strcmp(currentPos, positions));
                    nextIdx = mod(idx, length(positions)) + 1;
                    app.UIAxes.Legend.Location = positions{nextIdx}
                case 'yfxTab'
                    positions = {'northwest', 'southwest', 'southeast', 'northeast'};
                    currentPos = app.UIAxes_2.Legend.Location;
                    idx = find(strcmp(currentPos, positions));
                    nextIdx = mod(idx, length(positions)) + 1;
                    app.UIAxes_2.Legend.Location = positions{nextIdx}
            end
        end

        % Value changed function: Regras
        function RegrasValueChanged(app, event)
            ExecutarButtonPushed(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            ExecutarButton_2Pushed(app, [])
        end

        % Callback function: ColorPicker
        function ColorPickerValueChanged(app, event)
            ExecutarButton_2Pushed(app, [])
        end

        % Button pushed function: ConverterButton
        function ConverterButtonPushed(app, event)
            
        end

        % Value changed function: ConversoSwitch
        function ConversoSwitchValueChanged(app, event)
            ConverterButtonPushed(app, [])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FicheiroMenu = uimenu(app.UIFigure);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.ObterPDFMenu = uimenu(app.FicheiroMenu);
            app.ObterPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ObterPDFMenuSelected, true);
            app.ObterPDFMenu.Text = 'Obter PDF';
            app.RelatrioMenu = uimenu(app.FicheiroMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.PginaXPTOMenu = uimenu(app.FicheiroMenu);
            app.PginaXPTOMenu.MenuSelectedFcn = createCallbackFcn(app, @PginaXPTOMenuSelected, true);
            app.PginaXPTOMenu.Text = 'PáginaXPTO';
            app.SairMenu = uimenu(app.FicheiroMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.DefiniesMenu = uimenu(app.UIFigure);
            app.DefiniesMenu.Text = 'Definições';
            app.AtivarGrelhaMenu = uimenu(app.DefiniesMenu);
            app.AtivarGrelhaMenu.MenuSelectedFcn = createCallbackFcn(app, @AtivarGrelhaMenuSelected, true);
            app.AtivarGrelhaMenu.Text = 'Ativar Grelha';
            app.MudarPosLegendaMenu = uimenu(app.DefiniesMenu);
            app.MudarPosLegendaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPosLegendaMenuSelected, true);
            app.MudarPosLegendaMenu.Text = 'Mudar Pos. Legenda';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = 'z=f(x,y)';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) =';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.aEditFieldLabel_3 = uilabel(app.Panel);
            app.aEditFieldLabel_3.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x =';
            app.Label = uilabel(app.Panel);
            app.Label.Text = ';';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ';';
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = ';';
            app.aEditFieldLabel_4 = uilabel(app.Panel);
            app.aEditFieldLabel_4.Text = 'd';
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.aEditFieldLabel_5 = uilabel(app.Panel);
            app.aEditFieldLabel_5.Text = 'h2';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ';';
            app.cLabel = uilabel(app.Panel);
            app.cLabel.Text = 'c';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y =';
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.Text = 'Reset';
            app.LabelDParcial = uilabel(app.Panel);
            app.LabelDParcial.Interpreter = 'latex';
            app.LabelDParcial.Text = 'DParcial';
            app.Panel_3 = uipanel(app.Panel);
            app.LabelEqLaplace = uilabel(app.Panel_3);
            app.LabelEqLaplace.Interpreter = 'latex';
            app.LabelEqLaplace.Text = 'EqLaplace';
            app.LabelHarmonica = uilabel(app.Panel_3);
            app.LabelHarmonica.Text = 'A função não é harmónica';
            app.Regras = uidropdown(app.Panel);
            app.Regras.Items = {'Trapézio', 'Simpson', 'Todos'};
            app.Regras.ValueChangedFcn = createCallbackFcn(app, @RegrasValueChanged, true);
            app.Regras.Value = 'Trapézio';
            app.RegrasLabel = uilabel(app.Panel);
            app.RegrasLabel.Text = 'Regras';
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = 'y = f(x)';
            app.UIAxes_2 = uiaxes(app.yfxTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_2 = uipanel(app.yfxTab);
            app.fxLabel = uilabel(app.Panel_2);
            app.fxLabel.Text = 'f(x) = ';
            app.eFx_2 = uieditfield(app.Panel_2, 'text');
            app.eFx_2.Value = 'sin(x)';
            app.aEditFieldLabel_6 = uilabel(app.Panel_2);
            app.aEditFieldLabel_6.Text = 'a';
            app.eA_2 = uieditfield(app.Panel_2, 'numeric');
            app.eA_2.Value = -6;
            app.aEditFieldLabel_7 = uilabel(app.Panel_2);
            app.aEditFieldLabel_7.Text = 'h1';
            app.eH1_2 = uieditfield(app.Panel_2, 'numeric');
            app.eH1_2.Value = 0.2;
            app.aEditFieldLabel_8 = uilabel(app.Panel_2);
            app.aEditFieldLabel_8.Text = 'b';
            app.eB_2 = uieditfield(app.Panel_2, 'numeric');
            app.eB_2.Value = 6;
            app.xLabel_2 = uilabel(app.Panel_2);
            app.xLabel_2.Text = 'x =';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = ';';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = ';';
            app.ExecutarButton_2 = uibutton(app.Panel_2, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = 'Executar';
            app.ResetButton_2 = uibutton(app.Panel_2, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.FormulaSelecionada = uibuttongroup(app.Panel_2);
            app.FormulaSelecionada.Title = 'Fórmulas de Diferenças Finitas';
            app.ProgressivasButton = uitogglebutton(app.FormulaSelecionada);
            app.ProgressivasButton.Text = 'Progressivas';
            app.ProgressivasButton.Value = true;
            app.RegressivasButton = uitogglebutton(app.FormulaSelecionada);
            app.RegressivasButton.Text = 'Regressivas';
            app.CentradasButton = uitogglebutton(app.FormulaSelecionada);
            app.CentradasButton.Text = 'Centradas';
            app.CordasLinhasdosGrficosPanel = uipanel(app.Panel_2);
            app.CordasLinhasdosGrficosPanel.Title = 'Cor das Linhas dos Gráficos';
            app.DropDown = uidropdown(app.CordasLinhasdosGrficosPanel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.ColorPicker = uicolorpicker(app.CordasLinhasdosGrficosPanel);
            app.ColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorPickerValueChanged, true);
            app.DiferenasFinitasLabel = uilabel(app.Panel_2);
            app.DiferenasFinitasLabel.Text = 'Diferenças Finitas';
            app.Pol2CartTab = uitab(app.TabGroup);
            app.Pol2CartTab.Title = 'Pol2Cart';
            app.UIAxes2 = uiaxes(app.Pol2CartTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ConversodeCoordenadasPanel = uipanel(app.Pol2CartTab);
            app.ConversodeCoordenadasPanel.Title = 'Conversão de Coordenadas';
            app.ConversoSwitchLabel = uilabel(app.ConversodeCoordenadasPanel);
            app.ConversoSwitchLabel.Text = 'Conversão';
            app.ConversoSwitch = uiswitch(app.ConversodeCoordenadasPanel, 'slider');
            app.ConversoSwitch.Items = {'Polares', 'Cartesianas'};
            app.ConversoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConversoSwitchValueChanged, true);
            app.ConversoSwitch.Value = 'Polares';
            app.ConverterButton = uibutton(app.ConversodeCoordenadasPanel, 'push');
            app.ConverterButton.ButtonPushedFcn = createCallbackFcn(app, @ConverterButtonPushed, true);
            app.ConverterButton.Text = 'Converter';
            app.ResetButton_3 = uibutton(app.ConversodeCoordenadasPanel, 'push');
            app.ResetButton_3.Text = 'Reset';
            app.CoordenadasPanel = uipanel(app.ConversodeCoordenadasPanel);
            app.CoordenadasPanel.Title = 'Coordenadas';
            app.THETAEditFieldLabel = uilabel(app.CoordenadasPanel);
            app.THETAEditFieldLabel.Text = 'THETA';
            app.THETA = uieditfield(app.CoordenadasPanel, 'numeric');
            app.RHOEditFieldLabel = uilabel(app.CoordenadasPanel);
            app.RHOEditFieldLabel.Text = 'RHO';
            app.RHO = uieditfield(app.CoordenadasPanel, 'text');
            app.RHO.Value = '0';
            app.CoordenadasPolaresConvertidasparaCoordenadasCartesianasLabel = uilabel(app.ConversodeCoordenadasPanel);
            app.CoordenadasPolaresConvertidasparaCoordenadasCartesianasLabel.Text = {'Coordenadas Polares'; ''; 'Convertidas para'; ' '; 'Coordenadas Cartesianas'};
            app.PrximoButton = uibutton(app.Pol2CartTab, 'push');
            app.PrximoButton.Text = 'Próximo';
            app.AnteriorButton = uibutton(app.Pol2CartTab, 'push');
            app.AnteriorButton.Text = 'Anterior';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquina05CDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end